classdef MultTransITPCEyeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ShowFixOverlayBtn   matlab.ui.control.Button
        YLimEdit            matlab.ui.control.EditField
        CLimdBLabel_7       matlab.ui.control.Label
        RmFixOverlapCkb     matlab.ui.control.CheckBox
        SaccadeFlag         matlab.ui.control.CheckBox
        PermutationCkb      matlab.ui.control.CheckBox
        ShuffleCkb          matlab.ui.control.CheckBox
        OverlapTransTypeDD  matlab.ui.control.DropDown
        TransTypeLabel_18   matlab.ui.control.Label
        RmOverlapCkb        matlab.ui.control.CheckBox
        DescCkb             matlab.ui.control.CheckBox
        VelCkb              matlab.ui.control.CheckBox
        WalkCkb             matlab.ui.control.CheckBox
        PatientCkb          matlab.ui.control.CheckBox
        RegionCkb           matlab.ui.control.CheckBox
        TransCkb            matlab.ui.control.CheckBox
        SelDirEdit          matlab.ui.control.EditField
        SaveDirLabel        matlab.ui.control.Label
        VelTypeDD           matlab.ui.control.DropDown
        TransTypeLabel_16   matlab.ui.control.Label
        TransWinEdit        matlab.ui.control.EditField
        CLimdBLabel_6       matlab.ui.control.Label
        SaveFigCkb          matlab.ui.control.CheckBox
        SaveBtn             matlab.ui.control.Button
        CLim                matlab.ui.control.EditField
        CLimdBLabel_2       matlab.ui.control.Label
        PvalEdit            matlab.ui.control.NumericEditField
        NormLowLabel_3      matlab.ui.control.Label
        PlotButton          matlab.ui.control.Button
        PatientTypeDD       matlab.ui.control.DropDown
        TransTypeLabel_8    matlab.ui.control.Label
        WalkTypeDD          matlab.ui.control.DropDown
        TransTypeLabel_5    matlab.ui.control.Label
        RegionTypeDD        matlab.ui.control.DropDown
        TransTypeLabel_4    matlab.ui.control.Label
        DescTypeDD          matlab.ui.control.DropDown
        TransTypeLabel_2    matlab.ui.control.Label
        TransTypeDD         matlab.ui.control.DropDown
        TransTypeLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        RWA % realworld analysis object
        fH; %specgram
        CustomRegion1;
        aH1; %axis handle to itpc
        aH2; %axis handle to evoked potential
        pH;
        FixOverlay=false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin>1
                app.RWA = varargin{1};
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'Run getMultTransData first!','warning');
                    return;
                end
            else
                WaitH = uiprogressdlg(app.UIFigure,'Indeterminate','on','Message','Loading...');
                drawnow;
                pause(0.1);
                app.RWA = RWAnalysis2;
                app.RWA.loadMultData;
                close(WaitH);
                if isempty(app.RWA.MultTrans)
                    uialert(app.UIFigure,'RWAnalysis.mat file not found!','warning');
                    return;
                end
            end
            
            %transitions
            eventlist = unique(app.RWA.MultTrans.Evnt);
            app.TransTypeDD.Items = [{''},cellstr(eventlist)]; %app.TransTypeDD.ValueIndex = 1;

            %overlap transitions (to be removed if overlap checkbox is checked)
            app.OverlapTransTypeDD.Items = [{''},cellstr(eventlist)]; 

            %descriptions
            desclist = regexp(app.RWA.MultTrans.Desc,'\[([a-zA-Z0-9]+)\]','tokens');
            desclist(cellfun(@isempty,desclist)) = [];
            desclist = cellfun(@(x)[x{:}],desclist,'UniformOutput',false);
            desclist = unique(cat(2,desclist{:}));
            app.DescTypeDD.Items = [{''},cellstr(desclist)]; %app.DescTypeDD.ValueIndex = 1;

            %regions
            regionlist = {'AntHipp','LatTemp','Ent+Peri','PostHipp+Para','All Chans','Custom'};
            app.RegionTypeDD.Items = [{''},regionlist]; %app.RegionTypeDD.ValueIndex = 1;

            %patient
            patientlist = {'All Patients','Leave Out 1','Leave Out 2','Leave Out 3','Leave Out 4','Leave Out 5','1','2','3','4','5'};
            app.PatientTypeDD.Items = [{''},patientlist]; app.PatientTypeDD.Value = 'All Patients';

            %walk
            walklist = {'First Walks','Last Walks','Stop Walks','Go Walks','All Walks','1','2','3','4','5','6','7','8'};
            app.WalkTypeDD.Items = [{''},walklist]; app.WalkTypeDD.Value = 'All Walks';

            %velocity
            vellist = {'VelHigh','VelLow','VelHighTercile','VelMidTercile','VelLowTercile','VelHigh10','VelLow10'};
            app.VelTypeDD.Items = [{''},vellist]; app.VelTypeDD.Value = '';

            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            transtype = app.TransTypeDD.Value;
            desctype = app.DescTypeDD.Value;
            regiontype = app.RegionTypeDD.Value;
            patienttype = app.PatientTypeDD.Value;
            walktype = app.WalkTypeDD.Value;
            veltype = app.VelTypeDD.Value;
            customregion = app.CustomRegion1;
            overlapevnts = app.OverlapTransTypeDD.Value;
            transrng = str2num(app.TransWinEdit.Value);
            pval = app.PvalEdit.Value;
            rmoverlap = app.RmOverlapCkb.Value; %remove overlapping trials
            shuffleflag = app.ShuffleCkb.Value; 
            permflag = app.PermutationCkb.Value;
            saccadeflag = app.SaccadeFlag.Value;
            rmfixoverlap = app.RmFixOverlapCkb.Value;
           
            b = [];
            % b(1) = isempty(transtype) && ~app.TransCkb.Value;
            b(2) = isempty(regiontype) && ~app.RegionCkb.Value;
            b(3) = isempty(patienttype) && ~app.PatientCkb.Value;
            b(4) = isempty(walktype) && ~app.WalkCkb.Value;
            b(5) = strcmp(regiontype,'Custom') && isempty(customregion);

            %Perform some checks
            if any(b)
                uialert(app.UIFigure,'Make sure to select all options!','error');
                return;
            end

            %Plot
            if app.TransCkb.Value
                transcell = app.TransTypeDD.Items(2:end);
            else
                transcell = {transtype};
            end
            if app.RegionCkb.Value
                regioncell = app.RegionTypeDD.Items(2:end-1);
            else
                regioncell = {regiontype};
            end
            if app.PatientCkb.Value
                patientcell = app.PatientTypeDD.Items(2:end);
            else
                patientcell = {patienttype};
            end
            if app.WalkCkb.Value
                walkcell = app.WalkTypeDD.Items(2:end);
            else
                walkcell = {walktype};
            end
            if app.VelCkb.Value
                velcell = app.VelTypeDD.Items(2:end);
            else
                velcell = {veltype};
            end

            ntrans = length(transcell);
            nregion = length(regioncell);
            npatient = length(patientcell);
            nwalk = length(walkcell);
            nvel = length(velcell);
            ntotal = ntrans*nregion*npatient*nwalk*nvel;

            [tidx,ridx,pidx,widx,vidx] = ndgrid(1:ntrans,1:nregion,1:npatient,1:nwalk,1:nvel);
            idx = [tidx(:),ridx(:),pidx(:),widx(:),vidx(:)];

            if size(idx,1)~=ntotal
                uialert(app.UIFigure,'Iteration size mismatch!','error');
                return;
            end

            iterflag = any([ntrans,nregion,npatient,nwalk,nvel,ntotal]>1);
            if iterflag && (app.SaveFigCkb.Value || app.SaveRawCkb.Value)
                uialert(app.UIFigure,'Uncheck "Raw" or "Fig"','error');
                return;
            end

            if iterflag
                sel = uiconfirm(app.UIFigure,sprintf('%d iterations will be performed and saved. Continue?',ntotal),'Continue?');
                switch sel
                    case 'Cancel'
                        return;
                end
            end

            pb = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Generating plot...');
            for k=1:ntotal
                pb.Value = (k/ntotal);

                if ntotal>1
                    if any(ishandle(app.fH)); close(app.fH); end
                end
                app.fH = app.RWA.plotMultTransITPCEye('transtype',transcell{idx(k,1)},'regiontype',regioncell{idx(k,2)},...
                    'walktype',walkcell{idx(k,4)},'clim',str2num(app.CLim.Value),...
                    'desctype',desctype,'patienttype',patientcell{idx(k,3)},'pval',pval,'transrng',transrng,...
                    'veltype',velcell{idx(k,5)},...
                    'customregion',customregion,'rmoverlap',rmoverlap,...
                    'overlapevnts',overlapevnts,'shuffleflag',shuffleflag,...
                    'permflag',permflag,'saccadeflag',saccadeflag,...
                    'ylim',str2num(app.YLimEdit.Value),'rmfixoverlap',rmfixoverlap);
                app.aH1 = app.fH.UserData.aH1;
                app.aH2 = app.fH.UserData.aH2;
                app.pH = app.fH.UserData.pH;
                app.FixOverlay = false;
                drawnow; pause(0.1);
                if iterflag
                    app.SaveBtnFcn;
                end
            end
            close(pb);


        end

        % Button pushed function: SaveBtn
        function SaveBtnFcn(app, event)
            if any(ishandle(app.fH))
                app.SaveBtn.BackgroundColor = 'r';
                if isempty(app.SelDirEdit.Value)
                    app.SelDirEdit.Value = lastDir('Choose the destination folder...');
                end
                if ~isfolder(app.SelDirEdit.Value)
                    mkdir(app.SelDirEdit.Value);
                end
                tH = get(app.aH1(end),'title');
                tStr = tH.String;
                tStr = regexprep(tStr,'\s+','');
                tStr = regexprep(tStr,'\\|/','-');
                tStr = regexprep(tStr,'<','');
                tStr = regexprep(tStr,'::','~');
                tStr = regexprep(tStr,'\|','+');
                tStr = regexprep(tStr,'\(',',');
                tStr = regexprep(tStr,'\)','');
                tStr = regexprep(tStr,'=','');
                tStr = cell2mat(reshape(tStr,1,[]));
                fstr = sprintf('ITPCGUI_%s.png',tStr);
                print(app.fH,fullfile(app.SelDirEdit.Value,fstr),'-dpng','-r300');
                if app.SaveFigCkb.Value
                    savefig(app.fH,fullfile(app.SelDirEdit.Value,regexprep(fstr,'png$','fig')))
                end
                app.SaveBtn.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Value changed function: RegionTypeDD
        function RegionTypeFcn(app, event)
            if strcmp(app.RegionTypeDD.Value,'Custom')
                CustomRegionGUI(app,1);
            end            
        end

        % Value changed function: CLim
        function CLimFcn(app, event)
            if any(ishandle(app.aH1))
                set(app.aH1,'clim',str2num(app.CLim.Value))
            end
        end

        % Value changed function: YLimEdit
        function YLimEditValueChanged(app, event)
            if any(ishandle(app.aH2))
                set(app.aH2,'ylim',str2num(app.YLimEdit.Value))
            end
        end

        % Button pushed function: ShowFixOverlayBtn
        function ShowFixOverlayBtnButtonPushed(app, event)
            if app.FixOverlay
                set(app.pH,'visible','off');
                app.aH2.YAxis(2).Visible = 'off';
                app.FixOverlay = false;
            else
                set(app.pH,'visible','on');
                app.aH2.YAxis(2).Visible = 'on';
                app.FixOverlay = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransTypeLabel = uilabel(app.UIFigure);
            app.TransTypeLabel.Text = 'Transition';
            app.TransTypeDD = uidropdown(app.UIFigure);
            app.TransTypeDD.Items = {''};
            app.TransTypeDD.Editable = 'on';
            app.TransTypeDD.Tooltip = {'| symbol can be added between transitions to include more than one'};
            app.TransTypeDD.Value = '';
            app.TransTypeLabel_2 = uilabel(app.UIFigure);
            app.TransTypeLabel_2.Text = 'Description (opt)';
            app.DescTypeDD = uidropdown(app.UIFigure);
            app.DescTypeDD.Items = {''};
            app.DescTypeDD.Editable = 'on';
            app.DescTypeDD.Tooltip = {'| symbol can be added between each description to include more than one'};
            app.DescTypeDD.Value = '';
            app.TransTypeLabel_4 = uilabel(app.UIFigure);
            app.TransTypeLabel_4.Text = 'Region';
            app.RegionTypeDD = uidropdown(app.UIFigure);
            app.RegionTypeDD.Items = {''};
            app.RegionTypeDD.Editable = 'on';
            app.RegionTypeDD.ValueChangedFcn = createCallbackFcn(app, @RegionTypeFcn, true);
            app.RegionTypeDD.Tooltip = {'| symbol can be added between region names (except custom) to include more than one'};
            app.RegionTypeDD.Value = '';
            app.TransTypeLabel_5 = uilabel(app.UIFigure);
            app.TransTypeLabel_5.Text = 'Walk';
            app.WalkTypeDD = uidropdown(app.UIFigure);
            app.WalkTypeDD.Items = {''};
            app.WalkTypeDD.Editable = 'on';
            app.WalkTypeDD.Tooltip = {'A list of walk numbers can be added manually (i.e. 1,2,7)'};
            app.WalkTypeDD.Value = '';
            app.TransTypeLabel_8 = uilabel(app.UIFigure);
            app.TransTypeLabel_8.Text = 'Patient';
            app.PatientTypeDD = uidropdown(app.UIFigure);
            app.PatientTypeDD.Items = {''};
            app.PatientTypeDD.Editable = 'on';
            app.PatientTypeDD.Tooltip = {'A list of patient numbers can be added manually (i.e. 1,3,4)'};
            app.PatientTypeDD.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NormLowLabel_3 = uilabel(app.UIFigure);
            app.NormLowLabel_3.Text = 'Pval';
            app.PvalEdit = uieditfield(app.UIFigure, 'numeric');
            app.PvalEdit.Limits = [0 1];
            app.PvalEdit.Value = 0.05;
            app.CLimdBLabel_2 = uilabel(app.UIFigure);
            app.CLimdBLabel_2.Text = 'CLim';
            app.CLim = uieditfield(app.UIFigure, 'text');
            app.CLim.ValueChangedFcn = createCallbackFcn(app, @CLimFcn, true);
            app.CLim.Tooltip = {'Color limits in units of zscore for plotting specgram (enter zero to hide specgram)'};
            app.CLim.Value = '-10,10';
            app.SaveBtn = uibutton(app.UIFigure, 'push');
            app.SaveBtn.ButtonPushedFcn = createCallbackFcn(app, @SaveBtnFcn, true);
            app.SaveBtn.Text = 'Save';
            app.SaveFigCkb = uicheckbox(app.UIFigure);
            app.SaveFigCkb.Text = 'Fig';
            app.CLimdBLabel_6 = uilabel(app.UIFigure);
            app.CLimdBLabel_6.Text = 'Window';
            app.TransWinEdit = uieditfield(app.UIFigure, 'text');
            app.TransWinEdit.Tooltip = {'Transition window range in sec'};
            app.TransWinEdit.Value = '-3,3';
            app.TransTypeLabel_16 = uilabel(app.UIFigure);
            app.TransTypeLabel_16.Text = 'Velocity';
            app.VelTypeDD = uidropdown(app.UIFigure);
            app.VelTypeDD.Items = {''};
            app.VelTypeDD.Editable = 'on';
            app.VelTypeDD.Tooltip = {'These represent the amount of velocity change in +-5sec window centered on transition'};
            app.VelTypeDD.Value = '';
            app.SaveDirLabel = uilabel(app.UIFigure);
            app.SaveDirLabel.Text = 'Save Dir';
            app.SelDirEdit = uieditfield(app.UIFigure, 'text');
            app.TransCkb = uicheckbox(app.UIFigure);
            app.TransCkb.Tooltip = {'Iterate and save'};
            app.TransCkb.Text = '';
            app.RegionCkb = uicheckbox(app.UIFigure);
            app.RegionCkb.Tooltip = {'Iterate and save'};
            app.RegionCkb.Text = '';
            app.PatientCkb = uicheckbox(app.UIFigure);
            app.PatientCkb.Tooltip = {'Iterate and save'};
            app.PatientCkb.Text = '';
            app.WalkCkb = uicheckbox(app.UIFigure);
            app.WalkCkb.Tooltip = {'Iterate and save'};
            app.WalkCkb.Text = '';
            app.VelCkb = uicheckbox(app.UIFigure);
            app.VelCkb.Tooltip = {'Iterate and save'};
            app.VelCkb.Text = '';
            app.DescCkb = uicheckbox(app.UIFigure);
            app.DescCkb.Enable = 'off';
            app.DescCkb.Text = '';
            app.RmOverlapCkb = uicheckbox(app.UIFigure);
            app.RmOverlapCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.RmOverlapCkb.Text = 'No Overlap (Trans)';
            app.RmOverlapCkb.Value = true;
            app.TransTypeLabel_18 = uilabel(app.UIFigure);
            app.TransTypeLabel_18.Text = 'Overlap Transitions';
            app.OverlapTransTypeDD = uidropdown(app.UIFigure);
            app.OverlapTransTypeDD.Items = {''};
            app.OverlapTransTypeDD.Editable = 'on';
            app.OverlapTransTypeDD.Tooltip = {'Remove any trials where the overlap transition type overlaps with the main transition type.'};
            app.OverlapTransTypeDD.Value = '';
            app.ShuffleCkb = uicheckbox(app.UIFigure);
            app.ShuffleCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.ShuffleCkb.Text = 'Shuffle';
            app.PermutationCkb = uicheckbox(app.UIFigure);
            app.PermutationCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.PermutationCkb.Text = 'Run Permutations';
            app.PermutationCkb.Value = true;
            app.SaccadeFlag = uicheckbox(app.UIFigure);
            app.SaccadeFlag.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.SaccadeFlag.Text = 'Saccade';
            app.RmFixOverlapCkb = uicheckbox(app.UIFigure);
            app.RmFixOverlapCkb.Tooltip = {'Remove any overlapping trials of the same transistion type.'};
            app.RmFixOverlapCkb.Text = 'No Overlap (Fix)';
            app.CLimdBLabel_7 = uilabel(app.UIFigure);
            app.CLimdBLabel_7.Text = 'YLim';
            app.YLimEdit = uieditfield(app.UIFigure, 'text');
            app.YLimEdit.ValueChangedFcn = createCallbackFcn(app, @YLimEditValueChanged, true);
            app.YLimEdit.Tooltip = {'Color limits in units of zscore for plotting specgram (enter zero to hide specgram)'};
            app.YLimEdit.Value = '-20,20';
            app.ShowFixOverlayBtn = uibutton(app.UIFigure, 'push');
            app.ShowFixOverlayBtn.ButtonPushedFcn = createCallbackFcn(app, @ShowFixOverlayBtnButtonPushed, true);
            app.ShowFixOverlayBtn.Text = 'Show Fix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultTransITPCEyeGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end